classdef Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
    end

    
    properties (Access = public)  
    end

 
    methods (Access = public)

        function createUI(app, data, emotion) 
            % Emotions: 'Happy', 'Sad', 'Dynamic', 'Relaxed'
            SetBackground(app, emotion);


            CreateSongResultsUI(app, data);
        end

        function SetBackground(app, emotion) 
            calmImgSrc = 'CalmButtonNoText.jpg';
            happyImgSrc = 'HappyButtonNoText.jpg';
            sadImgSrc = 'SadButtonNoText.jpg';
            dynamicImgSrc = 'HypeButtonNoText.jpg';

            if(strcmp(emotion, "Happy"))
                app.Image.ImageSource = happyImgSrc;
            end
            
            if(strcmp(emotion, "Calm"))
                app.Image.ImageSource = calmImgSrc;
            end
            
            if(strcmp(emotion, "Sad"))
                app.Image.ImageSource = sadImgSrc;
            end
            
            if(strcmp(emotion, "Dynamic"))
                app.Image.ImageSource = dynamicImgSrc;
            end
            
        end

        function CreateSongResultsUI(app, data)
            screenPosition = get(app.UIFigure,'Position');
            screenWidth = screenPosition(3);
            screenHeight = screenPosition(4);

            sections = 6;
            paddingHeight = 10; 
            paddingWidth  = 20;     

            sectionHeight = (screenHeight-(paddingHeight*sections)) / sections; 
            sectionWidth  = (screenWidth-paddingWidth*2) / 4;
            
            startX = 0;
            startY = 0; 

            for(i = 1:5)
                songTitle = data.name(i);
                artist = data.artist(i);
                coverImgSrc = data.cover(i);
                
                startX = paddingWidth + sectionWidth*2;
                startY = sectionHeight*(sections-i)-paddingHeight*(i-1);

                coverPosition = [startX startY sectionHeight sectionHeight]
                titlePosition = [startX+sectionHeight+paddingWidth startY sectionWidth*2 sectionHeight]
                 
                CreateSongResult(app, songTitle, artist, coverImgSrc, i, coverPosition, titlePosition); 
            end

            
            recsButtonPosition = [startX startY sectionHeight sectionHeight]
            moreRecsButton = uibutton(app.UIFigure, "Position", recsButtonPosition)
       end

       function CreateSongResult(app, songTitle, artist, coverImgSrc, num, coverPosition, titlePosition)            
            fileUrl = strcat('image',num2str(num),'.png')
            urlwrite(coverImgSrc{1}, fileUrl);
            cover = uiimage(app.UIFigure,'ImageSource',fileUrl,'ScaleMethod','fit','Position',coverPosition);

            songName = uilabel(app.UIFigure, 'Text', strcat(num2str(num),'. ', songTitle,", ",artist), 'WordWrap', 'on', 'FontSize', 24, 'Position', titlePosition, 'FontColor','White');              
               
            % AUDIO CLIP
            % uihyperlink
            %songClip = uihtml(section);
            %urlwrite(audioSrc, 'testAudio.wav')
            
            % songClip.HTMLSource = '<audio controls style="padding-left:20px"><source src="/testAudio.wav" type="audio/wav"></audio>'
            % audioSrc = 'https://p.scdn.co/mp3-preview/1e7df67cef52cd4578f2a344f4bc875cac394301?cid=28ca2d3814414c08858c196d669a3838'
       end

       %function Create
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, emotion)
           createUI(app, data, emotion)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'BackG Gradient Design FINAL.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end